classdef xiaobiao < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Image             matlab.ui.control.Image
        Image_9           matlab.ui.control.Image
        Image_8           matlab.ui.control.Image
        Image_7           matlab.ui.control.Image
        Image_6           matlab.ui.control.Image
        SerialSwitch      matlab.ui.control.Switch
        Image_3           matlab.ui.control.Image
        TextReceive       matlab.ui.control.TextArea
        TextArea          matlab.ui.control.TextArea
        Label_10          matlab.ui.control.Label
        TextHexSend       matlab.ui.control.TextArea
        Label_9           matlab.ui.control.Label
        HexSend           matlab.ui.control.CheckBox
        ButtonClrSend     matlab.ui.control.Button
        ButtonSend        matlab.ui.control.Button
        ButtonClrReceive  matlab.ui.control.Button
        ButtonSave        matlab.ui.control.Button
        TextSend          matlab.ui.control.TextArea
        Label_8           matlab.ui.control.Label
        SerialState       matlab.ui.control.Lamp
        SerialParityBits  matlab.ui.control.DropDown
        Label_5           matlab.ui.control.Label
        SerialStopBits    matlab.ui.control.DropDown
        Label_4           matlab.ui.control.Label
        SerialDataBits    matlab.ui.control.DropDown
        Label_3           matlab.ui.control.Label
        SerialBaud        matlab.ui.control.DropDown
        Label_2           matlab.ui.control.Label
        SerialPortNumber  matlab.ui.control.DropDown
        FrictionstatemonitoringsystemforaerospacesystemLabel  matlab.ui.control.Label
        Label             matlab.ui.control.Label
        HexDisp           matlab.ui.control.CheckBox
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes7           matlab.ui.control.UIAxes
        UIAxes6           matlab.ui.control.UIAxes
        UIAxes5           matlab.ui.control.UIAxes
        UIAxes4           matlab.ui.control.UIAxes
        UIAxes1           matlab.ui.control.UIAxes
        UIAxes8           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SerialObject
        Str_Data
        Hex_Data
        Xlim%x的横坐标的步长
        XData%初始化坐标轴
        YData%初始化坐标轴
        pingjun%电流的平均值

    end

    
    methods (Access = private)
        
        function serialconfig(app)
            app.SerialPortNumber.Items=serialportlist("all");


                tu1 = "灯关.png";
                [im, ~, alpha] = imread(tu1);
                % 清空原有内容（可选）
                cla(app.UIAxes1);
                % 在指定 UIAxes 中显示
                h = imshow(im, 'Parent', app.UIAxes1);
                % 设置透明通道
                set(h, 'AlphaData', alpha);
                % 关闭坐标轴
                app.UIAxes1.Visible = 'off';


                tu2 = "电视关.png";
                [im, ~, alpha] = imread(tu2);
                % 清空原有内容（可选）
                cla(app.UIAxes2);
                % 在指定 UIAxes 中显示
                h = imshow(im, 'Parent', app.UIAxes2);
                % 设置透明通道
                set(h, 'AlphaData', alpha);
                % 关闭坐标轴
                app.UIAxes2.Visible = 'off';


                tu4 = "窗帘开.png";
                [im, ~, alpha] = imread(tu4);
                % 清空原有内容（可选）
                cla(app.UIAxes4);
                % 在指定 UIAxes 中显示
                h = imshow(im, 'Parent', app.UIAxes4);
                % 设置透明通道
                set(h, 'AlphaData', alpha);
                % 关闭坐标轴
                app.UIAxes4.Visible = 'off';
                
                tu5 = "风扇关.png";
                [im, ~, alpha] = imread(tu5);
                % 清空原有内容（可选）
                cla(app.UIAxes5);
                % 在指定 UIAxes 中显示
                h = imshow(im, 'Parent', app.UIAxes5);
                % 设置透明通道
                set(h, 'AlphaData', alpha);
                % 关闭坐标轴
                app.UIAxes5.Visible = 'off';

                tu6 = "加湿器关.png";
                [im, ~, alpha] = imread(tu6);
                % 清空原有内容（可选）
                cla(app.UIAxes6);
                % 在指定 UIAxes 中显示
                h = imshow(im, 'Parent', app.UIAxes6);
                % 设置透明通道
                set(h, 'AlphaData', alpha);
                % 关闭坐标轴
                app.UIAxes6.Visible = 'off';

                tu7 = "微波炉关.png";
                [im, ~, alpha] = imread(tu7);
                % 清空原有内容（可选）
                cla(app.UIAxes7);
                % 在指定 UIAxes 中显示
                h = imshow(im, 'Parent', app.UIAxes7);
                % 设置透明通道
                set(h, 'AlphaData', alpha);
                % 关闭坐标轴
                app.UIAxes7.Visible = 'off';


                tu8="背景.png";%读取图片
                im=imread(tu8);
                imshow(im,'Parent',app.UIAxes8);%在标签上显示图


            if(app.SerialSwitch.Value=='Open1')
                delete(app.SerialObject);
                app.SerialState.Color=[0,1,0];
                try
                    app.SerialObject=serialport(app.SerialPortNumber.Value, ...
                        str2double(app.SerialBaud.Value), ...    %把字符转换为数字
                        "DataBits",str2double(app.SerialDataBits.Value), ...
                        "StopBits",str2double(app.SerialStopBits.Value), ...
                        "Parity",app.SerialParityBits.Value);
                    configureTerminator(app.SerialObject,"LF");
                    configureCallback(app.SerialObject,"terminator",@serialcallback);
                catch
                    msgbox('串口打开失败');
                    app.SerialState.Color=[0.5,0.5,0.5];
                    app.SerialSwitch.Value='Close';
                    delete(app.SerialObject);
                end
            elseif(app.SerialSwitch.Value=='Close')
                delete(app.SerialObject);
                app.SerialState.Color=[0.5,0.5,0.5];
            end
        
            function serialcallback(src,event)
                rl=readline(app.SerialObject);
                y=erase(rl,"0000EB90");%当前的y的值，只是一个点的值。删除其中的符号

                app.TextArea.Value=[app.TextArea.Value;y];%文本显示框里显示的
                y=str2double(y);%把字符转换成数字
      

                if (length(app.TextArea.Value)) > 10
                    app.TextArea.Value = app.TextArea.Value(2:end,:);%数组长度一旦超过15就丢弃第1行的值，这个数组是n行的，和下面的不一样
                end


                app.Str_Data=app.TextArea.Value;
                app.Str_Data(1)=[];            
                app.Hex_Data='';
                num_cell=length(app.Str_Data);%已接收的数据行数
                for i=1:num_cell
                    temp=cellstr(dec2hex(abs(app.Str_Data{i})));
                    len=length(temp);
                    for j=1:len
                        if(length(temp{j})==2)
                            app.Hex_Data=strcat(app.Hex_Data,32,temp{j});
                        else
                            app.Hex_Data=strcat(app.Hex_Data,32,'0',temp{j});
                        end
                    end
                    app.Hex_Data=strcat(app.Hex_Data,32,'0A');
                end
                    app.Hex_Data(1)='';
                
                if(app.HexDisp.Value==1)
                    app.TextReceive.Value=app.Hex_Data;
                else
                    app.TextReceive.Value=app.Str_Data;
                end   


                if y==4455%如果值为55
                    tu1 = "灯开.png";
                    [im, ~, alpha] = imread(tu1);
                    % 清空原有内容（可选）
                    cla(app.UIAxes1);
                    % 在指定 UIAxes 中显示
                    h = imshow(im, 'Parent', app.UIAxes1);
                    % 设置透明通道
                    set(h, 'AlphaData', alpha);
                    % 关闭坐标轴
                    app.UIAxes1.Visible = 'off';             
                elseif y==4466%如果值为66
                    tu1 = "灯关.png";
                    [im, ~, alpha] = imread(tu1);
                    % 清空原有内容（可选）
                    cla(app.UIAxes1);
                    % 在指定 UIAxes 中显示
                    h = imshow(im, 'Parent', app.UIAxes1);
                    % 设置透明通道
                    set(h, 'AlphaData', alpha);
                    % 关闭坐标轴
                    app.UIAxes1.Visible = 'off';                    

                elseif y==55%如果值为
                    tu2 = "电视开.png";
                    [im, ~, alpha] = imread(tu2);
                    % 清空原有内容（可选）
                    cla(app.UIAxes2);
                    % 在指定 UIAxes 中显示
                    h = imshow(im, 'Parent', app.UIAxes2);
                    % 设置透明通道
                    set(h, 'AlphaData', alpha);
                    % 关闭坐标轴
                    app.UIAxes2.Visible = 'off'; 
                elseif y==66%如果值为
                    tu2 = "电视关.png";
                    [im, ~, alpha] = imread(tu2);
                    % 清空原有内容（可选）
                    cla(app.UIAxes2);
                    % 在指定 UIAxes 中显示
                    h = imshow(im, 'Parent', app.UIAxes2);
                    % 设置透明通道
                    set(h, 'AlphaData', alpha);
                    % 关闭坐标轴
                    app.UIAxes2.Visible = 'off'; 


                elseif y==1155%如果值为
                    tu3 = "空调开.png";
                    [im, ~, alpha] = imread(tu3);
                    % 清空原有内容（可选）
                    cla(app.UIAxes3);
                    % 在指定 UIAxes 中显示
                    h = imshow(im, 'Parent', app.UIAxes3);
                    % 设置透明通道
                    set(h, 'AlphaData', alpha);
                    % 关闭坐标轴
                    app.UIAxes3.Visible = 'off'; 


                elseif y==3355%如果值为
                    tu4 = "窗帘开.png";
                    [im, ~, alpha] = imread(tu4);
                    % 清空原有内容（可选）
                    cla(app.UIAxes4);
                    % 在指定 UIAxes 中显示
                    h = imshow(im, 'Parent', app.UIAxes4);
                    % 设置透明通道
                    set(h, 'AlphaData', alpha);
                    % 关闭坐标轴
                    app.UIAxes4.Visible = 'off'; 
                elseif y==3366%如果值为
                    tu4 = "窗帘关.png";
                    [im, ~, alpha] = imread(tu4);
                    % 清空原有内容（可选）
                    cla(app.UIAxes4);
                    % 在指定 UIAxes 中显示
                    h = imshow(im, 'Parent', app.UIAxes4);
                    % 设置透明通道
                    set(h, 'AlphaData', alpha);
                    % 关闭坐标轴
                    app.UIAxes4.Visible = 'off'; 


                elseif y==2255%如果值为
                    tu5 = "风扇开.png";
                    [im, ~, alpha] = imread(tu5);
                    % 清空原有内容（可选）
                    cla(app.UIAxes5);
                    % 在指定 UIAxes 中显示
                    h = imshow(im, 'Parent', app.UIAxes5);
                    % 设置透明通道
                    set(h, 'AlphaData', alpha);
                    % 关闭坐标轴
                    app.UIAxes5.Visible = 'off'; 
                elseif y==2266%如果值为
                    tu5 = "风扇关.png";
                    [im, ~, alpha] = imread(tu5);
                    % 清空原有内容（可选）
                    cla(app.UIAxes5);
                    % 在指定 UIAxes 中显示
                    h = imshow(im, 'Parent', app.UIAxes5);
                    % 设置透明通道
                    set(h, 'AlphaData', alpha);
                    % 关闭坐标轴
                    app.UIAxes5.Visible = 'off'; 


                elseif y==6655%如果值为
                    tu6 = "加湿器开.png";
                    [im, ~, alpha] = imread(tu6);
                    % 清空原有内容（可选）
                    cla(app.UIAxes6);
                    % 在指定 UIAxes 中显示
                    h = imshow(im, 'Parent', app.UIAxes6);
                    % 设置透明通道
                    set(h, 'AlphaData', alpha);
                    % 关闭坐标轴
                    app.UIAxes6.Visible = 'off'; 
                elseif y==6666%如果值为
                    tu6 = "加湿器关.png";
                    [im, ~, alpha] = imread(tu6);
                    % 清空原有内容（可选）
                    cla(app.UIAxes6);
                    % 在指定 UIAxes 中显示
                    h = imshow(im, 'Parent', app.UIAxes6);
                    % 设置透明通道
                    set(h, 'AlphaData', alpha);
                    % 关闭坐标轴
                    app.UIAxes6.Visible = 'off';  


                elseif y==8855%如果值为
                    tu7 = "微波炉开.png";
                    [im, ~, alpha] = imread(tu7);
                    % 清空原有内容（可选）
                    cla(app.UIAxes7);
                    % 在指定 UIAxes 中显示
                    h = imshow(im, 'Parent', app.UIAxes7);
                    % 设置透明通道
                    set(h, 'AlphaData', alpha);
                    % 关闭坐标轴
                    app.UIAxes7.Visible = 'off'; 
                elseif y==8866%如果值为
                    tu7 = "微波炉关.png";
                    [im, ~, alpha] = imread(tu7);
                    % 清空原有内容（可选）
                    cla(app.UIAxes7);
                    % 在指定 UIAxes 中显示
                    h = imshow(im, 'Parent', app.UIAxes7);
                    % 设置透明通道
                    set(h, 'AlphaData', alpha);
                    % 关闭坐标轴
                    app.UIAxes7.Visible = 'off';  

                end


            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SerialPortNumber.Items=serialportlist("all");
            app.XData = 0;%初始化坐标轴的数据为0
            app.YData = 0;%初始化坐标轴的数据为0
            app.Xlim = 0;%初始化步长为0
            app.pingjun=0;%初始化数据的平均值为0
        end

        % Value changed function: SerialSwitch
        function SerialSwitchValueChanged(app, event)
            serialconfig(app);
        end

        % Button pushed function: ButtonSend
        function ButtonSendPushed(app, event)
            if(app.SerialSwitch.Value=='Open1')
                if(app.HexSend.Value==0)%正常发送
                    str=app.TextSend.Value;
                    cell_num=length(str);
                    if(cell_num==1)%
                        if((length(abs(str{1})))~=0)%判断是否有可以发送的数据
                            write(app.SerialObject,abs(str{1}),"uint8");
                        end
                    else
                        for i=1:cell_num-1
                            writeline(app.SerialObject,sprintf('%s\r',str{i})); % %s 0D 0A
                        end
                        if(length(abs(str{cell_num}))~=0)%最后一行是否是回车
                            write(app.SerialObject,abs(str{cell_num}),"uint8");
                        end
                    end
                elseif(app.HexSend.Value==1)%16进制发送
                    n=length(app.TextHexSend.Value);
                    if(n==1)
                        context=hex2dec(strsplit(app.TextHexSend.Value{1}));%16进制字符串转uint8数组
                        len=length(context);
                        uint8 send_flag;
                        send_flag=1;
                        if(len==0)
                            msgbox('发送内容不能为空')
                        else
                            for i=1:len
                                if(context(i)>255)
                                   send_flag=0;
                                end
                            end
                            if(send_flag)
                                write(app.SerialObject,context,"uint8");
                            else
                                msgbox('发送的数据必须为16进制数据');
                            end
                        end
                    elseif(n>1)
                        msgbox('16进制发送时请用0D 0A代替回车换行');
                    end
                end
            end
        end

        % Value changed function: SerialPortNumber
        function SerialPortNumberValueChanged(app, event)
            serialconfig(app); 
        end

        % Drop down opening function: SerialPortNumber
        function SerialPortNumberDropDownOpening(app, event)
            serialconfig(app); 
        end

        % Value changed function: SerialBaud
        function SerialBaudValueChanged(app, event)
            serialconfig(app); 
        end

        % Value changed function: SerialDataBits
        function SerialDataBitsValueChanged(app, event)
            serialconfig(app);
        end

        % Value changed function: SerialStopBits
        function SerialStopBitsValueChanged(app, event)
            serialconfig(app); 
        end

        % Value changed function: SerialParityBits
        function SerialParityBitsValueChanged(app, event)
            serialconfig(app); 
        end

        % Button pushed function: ButtonClrReceive
        function ButtonClrReceivePushed(app, event)
            app.TextReceive.Value='';
            app.TextArea.Value='';
            app.Str_Data='';
            app.Hex_Data='';
        end

        % Value changed function: HexDisp
        function HexDispValueChanged(app, event)
            value = app.HexDisp.Value;
                if(app.HexDisp.Value==1)
                    app.TextReceive.Value=app.Hex_Data;
                else
                    app.TextReceive.Value=app.Str_Data;
                end
        end

        % Button pushed function: ButtonSave
        function ButtonSavePushed(app, event)
        context=app.TextReceive.Value;%接收数据显示区
        arr=length(context);%数据行数
        if(app.HexDisp.Value==0)%保存字符串数据
            fid=fopen('data.txt','w+');
            for i=1:arr
                fprintf(fid,'%s\r\n',context{i}); %换行
            end
            fclose(fid);
        end
        
        if(app.HexDisp.Value==1)%保存16进制数据
            fid=fopen('data_hex.txt','w+');
                fprintf(fid,'%s\r\n',context{1}); %换行
            fclose(fid);
        end
        end

        % Button pushed function: ButtonClrSend
        function ButtonClrSendPushed(app, event)
            app.TextSend.Value='';
            app.TextHexSend.Value='';
        end

        % Value changed function: HexSend
        function HexSendValueChanged(app, event)
            value = app.HexSend.Value;
            if(value==1)%普通字符转16进制
                app.TextSend.Visible=0;
                app.TextHexSend.Visible=1;
                temp=app.TextSend.Value;
                string s;
                s='';
                m=length(temp);
                if(m>1)%不止一行数据
                      for i=1:m-1
                        x=cellstr(dec2hex(abs(temp{i})));%字符串转为16进制字符元胞数组
         	    n=length(x);
                        if(n~=0)%这一行字符不为空字符
                            for j=1:n %将每行的数据转成16进制并进行拼接
                                s=strcat(s,32,x{j});
                            end
                        s=strcat(s,32,'0D',32,'0A');%每行的数据末尾补0D 0A
                        end
                    end
                        %最后一行为空，不补0D 0A
                        x=cellstr(dec2hex(abs(temp{m}))); 
                        n=length(x);%最后一行的hex字符个数
                        %当最后一行不为回车时进行这一步
                        flag=length(dec2hex(abs(temp{m})));%flag为0时最后一行有数据
                        if(flag)%最后一行为空字符
                            for j=1:n 
                                s=strcat(s,32,x{j});
                            end
                        end
                        s(1)=[];
                elseif(m==1)%只有1行字符串,切换十六进制
                       if(~isempty(abs(temp{1})))%数据不为空
                       x=cellstr(dec2hex(abs(temp{1})));%字符
                       n=length(x);%字符长度
                        for j=1:n 
                            s=strcat(s,32,x{j});
                        end
                        s(1)=[];
                       end
                end
                app.TextHexSend.Value=s;
            elseif(value==0)
                app.TextSend.Visible=1;
                app.TextHexSend.Visible=0;
                hex_temp=app.TextHexSend.Value
                l=length(hex_temp);%l为数据行数
                if(l==1)          
                    temp=(char(hex2dec(strsplit(hex_temp{1}))))';%类型为字符串，但会损失一个回车0D 0A，这是为什么捏?
                    assignin("base","temp",temp);
                    ugrp=abs(temp);
                    v=length(ugrp);%
                    if((v>=2)&&(ugrp(v)==10)&&(ugrp(v-1)==13))%末尾监测到0D0A
                        app.TextSend.Value=temp;
                        app.TextSend.Value{end+1}='';
                    else 
                        app.TextSend.Value=temp;
                    end
                elseif(l>1)
                    msgbox('16进制发送时请用0D 0A代替回车换行');
                    app.HexSend.Value=1;
                    app.TextSend.Visible=0;
                    app.TextHexSend.Visible=0;  
                end       
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, ' ')
            xlabel(app.UIAxes8, ' ')
            ylabel(app.UIAxes8, ' ')
            zlabel(app.UIAxes8, ' ')
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, ' ')
            xlabel(app.UIAxes1, ' ')
            ylabel(app.UIAxes1, ' ')
            zlabel(app.UIAxes1, ' ')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, ' ')
            xlabel(app.UIAxes4, ' ')
            ylabel(app.UIAxes4, ' ')
            zlabel(app.UIAxes4, ' ')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, ' ')
            xlabel(app.UIAxes5, ' ')
            ylabel(app.UIAxes5, ' ')
            zlabel(app.UIAxes5, ' ')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, ' ')
            xlabel(app.UIAxes6, ' ')
            ylabel(app.UIAxes6, ' ')
            zlabel(app.UIAxes6, ' ')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, ' ')
            xlabel(app.UIAxes7, ' ')
            ylabel(app.UIAxes7, ' ')
            zlabel(app.UIAxes7, ' ')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, ' ')
            xlabel(app.UIAxes2, ' ')
            ylabel(app.UIAxes2, ' ')
            zlabel(app.UIAxes2, ' ')
            app.HexDisp = uicheckbox(app.UIFigure);
            app.HexDisp.ValueChangedFcn = createCallbackFcn(app, @HexDispValueChanged, true);
            app.HexDisp.Text = 'Hex ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Serial port';
            app.FrictionstatemonitoringsystemforaerospacesystemLabel = uilabel(app.UIFigure);
            app.FrictionstatemonitoringsystemforaerospacesystemLabel.Text = 'Friction state monitoring system for aerospace system';
            app.SerialPortNumber = uidropdown(app.UIFigure);
            app.SerialPortNumber.DropDownOpeningFcn = createCallbackFcn(app, @SerialPortNumberDropDownOpening, true);
            app.SerialPortNumber.ValueChangedFcn = createCallbackFcn(app, @SerialPortNumberValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Baud rate';
            app.SerialBaud = uidropdown(app.UIFigure);
            app.SerialBaud.Items = {'115200', '9600', ''};
            app.SerialBaud.ValueChangedFcn = createCallbackFcn(app, @SerialBaudValueChanged, true);
            app.SerialBaud.Value = '115200';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Data bits';
            app.SerialDataBits = uidropdown(app.UIFigure);
            app.SerialDataBits.Items = {'8', '7', '6', '5'};
            app.SerialDataBits.ValueChangedFcn = createCallbackFcn(app, @SerialDataBitsValueChanged, true);
            app.SerialDataBits.Value = '8';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Stop bits';
            app.SerialStopBits = uidropdown(app.UIFigure);
            app.SerialStopBits.Items = {'1', '1.5', '2'};
            app.SerialStopBits.ValueChangedFcn = createCallbackFcn(app, @SerialStopBitsValueChanged, true);
            app.SerialStopBits.Value = '1';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Check bits';
            app.SerialParityBits = uidropdown(app.UIFigure);
            app.SerialParityBits.Items = {'none', 'even', 'odd'};
            app.SerialParityBits.ValueChangedFcn = createCallbackFcn(app, @SerialParityBitsValueChanged, true);
            app.SerialParityBits.Value = 'none';
            app.SerialState = uilamp(app.UIFigure);
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Visible = 'off';
            app.Label_8.Text = 'Send ';
            app.TextSend = uitextarea(app.UIFigure);
            app.ButtonSave = uibutton(app.UIFigure, 'push');
            app.ButtonSave.ButtonPushedFcn = createCallbackFcn(app, @ButtonSavePushed, true);
            app.ButtonSave.Text = 'Save data';
            app.ButtonClrReceive = uibutton(app.UIFigure, 'push');
            app.ButtonClrReceive.ButtonPushedFcn = createCallbackFcn(app, @ButtonClrReceivePushed, true);
            app.ButtonClrReceive.Text = 'Clear data';
            app.ButtonSend = uibutton(app.UIFigure, 'push');
            app.ButtonSend.ButtonPushedFcn = createCallbackFcn(app, @ButtonSendPushed, true);
            app.ButtonSend.Text = 'Send ';
            app.ButtonClrSend = uibutton(app.UIFigure, 'push');
            app.ButtonClrSend.ButtonPushedFcn = createCallbackFcn(app, @ButtonClrSendPushed, true);
            app.ButtonClrSend.Text = 'Clear ';
            app.HexSend = uicheckbox(app.UIFigure);
            app.HexSend.ValueChangedFcn = createCallbackFcn(app, @HexSendValueChanged, true);
            app.HexSend.Text = 'Hex ';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'Send ';
            app.TextHexSend = uitextarea(app.UIFigure);
            app.TextHexSend.Visible = 'off';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = 'Receive';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Visible = 'off';
            app.TextReceive = uitextarea(app.UIFigure);
            app.TextReceive.Editable = 'off';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, '狗.gif');
            app.SerialSwitch = uiswitch(app.UIFigure, 'slider');
            app.SerialSwitch.Items = {'Close', 'Open1'};
            app.SerialSwitch.ValueChangedFcn = createCallbackFcn(app, @SerialSwitchValueChanged, true);
            app.SerialSwitch.Value = 'Close';
            app.Image_6 = uiimage(app.UIFigure);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, '猫.gif');
            app.Image_7 = uiimage(app.UIFigure);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, '盆栽.gif');
            app.Image_8 = uiimage(app.UIFigure);
            app.Image_8.ImageSource = fullfile(pathToMLAPP, '沙发.gif');
            app.Image_9 = uiimage(app.UIFigure);
            app.Image_9.ImageSource = fullfile(pathToMLAPP, '钟.gif');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '空调关.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xiaobiao

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end